classdef celltracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NotreversalButton           matlab.ui.control.Button
        ReversalsCheckBox           matlab.ui.control.CheckBox
        ReversalButton              matlab.ui.control.Button
        MaxframesEditField          matlab.ui.control.NumericEditField
        MaxframesEditFieldLabel     matlab.ui.control.Label
        TrackframesLabel            matlab.ui.control.Label
        ClearpointButton            matlab.ui.control.Button
        RandompointButton           matlab.ui.control.Button
        ClicktrackstLamp            matlab.ui.control.Lamp
        ClicktrackstLampLabel       matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        NewexperimentButton         matlab.ui.control.Button
        MouseY                      matlab.ui.control.Label
        MouseX                      matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        AlltimesCheckBox            matlab.ui.control.CheckBox
        AllcellsCheckBox            matlab.ui.control.CheckBox
        NewcolorButton              matlab.ui.control.Button
        SetaxislimitsButton         matlab.ui.control.Button
        TimeSlider                  matlab.ui.control.Slider
        TimeSliderLabel             matlab.ui.control.Label
        TrackingcellIDSpinner       matlab.ui.control.Spinner
        TrackingcellIDSpinnerLabel  matlab.ui.control.Label
        NewtrackButton              matlab.ui.control.Button
        Status                      matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        frames % Frames, image data, time, drift
        t % Current time.
        tracks % Cell tracks.
        ctrid % Current track ID.
        xlims % base X limits
        ylims % base Y limits
        lim % Laser image.
        pts % Tracked points scatter object.
        pt % Plot for a random point.
        tracking % Tracking click toggle.
        fpath % Path to current experiment.
    end
    
    methods (Access = private)
        
        function newframe(app)
            if ((app.t < 1) || (app.t > numel(app.frames)))
                return;
            end
            app.Status.Visible = 'off';
            app.lim.CData = app.frames(app.t).l;
            set(app.UIAxes,'xlim',app.xlims+...
                app.frames(app.t).drift(1),...
                'ylim',app.ylims+app.frames(app.t).drift(2));
            app.TimeSlider.Value = app.t;
            xs = [];
            ys = [];
            cols = [];
            szs = [];
            
            cnt = 0;
            for tr = 1:numel(app.tracks)
                cnt = cnt + numel(app.tracks(tr).t);
                if (app.AllcellsCheckBox.Value || (tr == app.ctrid))
                    for ct = 1:numel(app.tracks(tr).t)
                        if app.AlltimesCheckBox.Value || ...
                                (app.tracks(tr).t(ct) == app.t)
                            xs = [xs; app.tracks(tr).x(ct)];
                            ys = [ys; app.tracks(tr).y(ct)];
                            if (app.tracks(tr).t(ct) == app.t)
                                szs = [szs; 20];
                                cols = [cols; [1 0 0]];
                            else
                                szs = [szs; 10];
                                cols = [cols; app.tracks(tr).col];
                            end
                            if (ismember(app.tracks(tr).t(ct),...
                                    app.tracks(tr).revs) && ...
                                    app.ReversalsCheckBox.Value)
                                xs = [xs; app.tracks(tr).x(ct)];
                                ys = [ys; app.tracks(tr).y(ct)];
                                cols = [cols; [0 1 0]];
                                szs = [szs; 20];
                            end
                        end
                    end
                end
            end
            app.TrackframesLabel.Text = sprintf('%d',cnt);

            app.pts.XData = xs;
            app.pts.YData = ys;
            app.pts.CData = cols;
            app.pts.SizeData = szs;

        end
        
        function settrack(app)
            if app.ctrid > app.TrackingcellIDSpinner.Limits(2)
                app.TrackingcellIDSpinner.Limits(2) = app.ctrid;
            end
            app.TrackingcellIDSpinner.Value = app.ctrid;
            app.TrackingcellIDSpinner.FontColor = app.tracks(app.ctrid).col;
        end

        function moveMouse(app)
            C = get(app.UIAxes,'CurrentPoint');
            app.MouseX.Text = sprintf('%3.3f',C(1,1));
            app.MouseY.Text = sprintf('%3.3f',C(1,2));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath("~/SimPaperAnalysis"));
            app.frames = [];
            app.tracks = [];
            app.ctrid = 1;
            app.t = 1;
            app.TimeSlider.Value = 1;
            app.tracking = false;
            hold(app.UIAxes,'on');

            app.tracks = [app.tracks; struct('x',[],'y',[],...
                't',[],'revs',[],'col',rand(1,3))];
            app.TrackingcellIDSpinner.FontColor = app.tracks(1).col;

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
                    app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
                    if app.t<numel(app.frames)
                        app.t = app.t+1;
                        app.newframe();
                        
                    end
                case 'leftarrow'
                    app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
                    app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
                    if app.t>1
                        app.t = app.t-1;
                        app.newframe();
                    end
                case 'c'
                    C = get(app.UIAxes,'CurrentPoint');
                    trct = app.tracks(app.ctrid).t == app.t;
                    if sum(trct)>0
                        app.tracks(app.ctrid).x(trct) = C(1,1);
                        app.tracks(app.ctrid).y(trct) = C(1,2);
                    else
                        app.tracks(app.ctrid).t = ...
                            [app.tracks(app.ctrid).t; app.t];
                        app.tracks(app.ctrid).x = ...
                            [app.tracks(app.ctrid).x; C(1,1)];
                        app.tracks(app.ctrid).y = ...
                            [app.tracks(app.ctrid).y; C(1,2)];
                    end
                    if app.t < numel(app.frames)
                        app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
                        app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
                        app.t = app.t + 1;
                        app.TimeSlider.Value = app.t;
                        app.newframe;
                    end
                case 't'
                    if app.tracking
                        app.tracking = false;
                        app.ClicktrackstLamp.Color = [1 0 0];
                    else
                        app.tracking = true;
                        app.ClicktrackstLamp.Color = [0 1 0];
                    end
                case 'r'
                    app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
                    app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
                    app.t = 1;
                    app.newframe;
                   
                otherwise
                    app.Status.Text = key;
                    app.Status.Visible = 'on';
            end
        end

        % Button pushed function: NewtrackButton
        function NewtrackButtonPushed(app, event)
            newtrack = struct('x',[],'y',[],'t',[],'revs',[],'col',rand(1,3));
            app.tracks = [app.tracks; newtrack];
            app.ctrid = app.TrackingcellIDSpinner.Limits(2) + 1;
            app.settrack;

        end

        % Button pushed function: SetaxislimitsButton
        function SetaxislimitsButtonPushed(app, event)
            app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
            app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
        end

        % Button pushed function: NewcolorButton
        function NewcolorButtonPushed(app, event)
            ncol = rand(1,3);
            app.tracks(app.TrackingcellIDSpinner.Value).col = ncol;
            app.TrackingcellIDSpinner.FontColor = ncol;
            app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
            app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
                    
            app.newframe;

        end

        % Value changed function: TrackingcellIDSpinner
        function TrackingcellIDSpinnerValueChanged(app, event)
            value = app.TrackingcellIDSpinner.Value;
            app.ctrid = value;
            app.settrack;
            app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
            app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
                    
            app.newframe;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [savename, savepath] = uiputfile();
            app.Status.Text = "Saving please wait...";
            app.Status.Visible = 'on';
            drawnow
            tracks = app.tracks;
            frames = app.frames;
            fpath = app.fpath;
            drifts = [];
            for fr = 1:numel(frames)
                drifts = [drifts; frames(fr).drift];
            end

            if savename
                save(fullfile(savepath,savename),"tracks","fpath","drifts");
            else
                app.Status.Text = "Save cancelled";
                app.Status.Visible = 'on';
                return;
            end
            app.Status.Visible = 'off';
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
            app.moveMouse();
        end

        % Button pushed function: NewexperimentButton
        function NewexperimentButtonPushed(app, event)
            app.fpath = uigetdir('/tigress/kc32/Monolayerpaperdata/Data');
            
            ts = getts(app.fpath);
            app.TimeSlider.Limits = [1 min(numel(ts),app.MaxframesEditField.Value)];

            ct = 1;
            l = laserdata(app.fpath,ct);
            l = l./imgaussfilt(l,128);
            l = rescale(l,0,1);
            drift = [0 0];

            framet = struct('t',ct,'l',l,'drift',drift);
            app.frames = [app.frames; framet];

            app.Status.Text = "Loading please wait...";
            app.Status.Visible = 'on';
            app.lim = imagesc(app.UIAxes,app.frames(1).l);
            colormap(app.UIAxes,gray);
            app.xlims = [1 1024];
            app.ylims = [1 768];
            drawnow
            for ct = 2:min(numel(ts),app.MaxframesEditField.Value)
                lp = l;
                l = laserdata(app.fpath,ct);
                l = l./imgaussfilt(l,128);
                l = rescale(l,0,1);
                sz = size(l);

                H = xcorr_fft(lp,l);
                P = xcorrpeak(H);
                drift = drift + [P(1)-sz(2)/2-1 P(2)-sz(1)/2-1];
                framest = struct('t',ct,'l',l,'drift',drift);
                app.frames = [app.frames; framest];
                app.TimeSlider.Value = ct;
                %app.newframe;
                drawnow
            end
            app.Status.Visible = 'off';

            hold(app.UIAxes,"on");
            app.pts = scatter(app.UIAxes,[],[],[],[],'filled');
            app.pt = scatter(app.UIAxes,[],[],20,'r');

            app.TimeSlider.Value = 1;
            app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
            app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
                    
            app.newframe();

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            fname = uigetfile();
            app.Status.Text = "Loading please wait...";
            app.Status.Visible = 'on';
            drawnow
            try
                load(fname,'tracks');
                if ~isfield(tracks,'revs')
                    for tr = 1:numel(tracks)
                        tracks(tr).revs = [];
                    end
                end
                app.tracks = tracks;
                app.t = 1;
                app.ctrid = numel(tracks);
                
                app.newframe;             
                app.settrack;
                app.Status.Visible = 'off';
            catch
                app.Status.Text = "Error loading file. New experiment first.";
                app.Status.Visible = 'on';
            end
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if app.tracking
                C = get(app.UIAxes,'CurrentPoint');
                trct = app.tracks(app.ctrid).t == app.t;
                if sum(trct)>0
                    app.tracks(app.ctrid).x(trct) = C(1,1);
                    app.tracks(app.ctrid).y(trct) = C(1,2);
                else
                    app.tracks(app.ctrid).t = ...
                        [app.tracks(app.ctrid).t; app.t];
                    app.tracks(app.ctrid).x = ...
                        [app.tracks(app.ctrid).x; C(1,1)];
                    app.tracks(app.ctrid).y = ...
                        [app.tracks(app.ctrid).y; C(1,2)];
                end
                if app.t < numel(app.frames)
                    app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
                    app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
                    app.t = app.t + 1;
                end
                app.TimeSlider.Value = app.t;
                 
                app.newframe();
            end
        end

        % Button pushed function: RandompointButton
        function RandompointButtonPushed(app, event)
            sz = size(app.frames(1).l);
            nx = randi(sz(2));
            ny = randi(sz(1));
            app.pt.XData = nx;
            app.pt.YData = ny;

        end

        % Button pushed function: ClearpointButton
        function ClearpointButtonPushed(app, event)
            app.pt.XData = [];
            app.pt.YData = [];
        end

        % Value changed function: AllcellsCheckBox
        function AllcellsCheckBoxValueChanged(app, event)
            app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
            app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
            
            app.newframe;
        end

        % Value changed function: AlltimesCheckBox
        function AlltimesCheckBoxValueChanged(app, event)
            app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
            app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
                    
            app.newframe;
        end

        % Button pushed function: ReversalButton
        function ReversalButtonPushed(app, event)
            app.tracks(app.ctrid).revs = [app.tracks(app.ctrid).revs; ...
                app.t];
            app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
            app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
                    
            app.newframe;
        end

        % Value changed function: ReversalsCheckBox
        function ReversalsCheckBoxValueChanged(app, event)
            app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
            app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
                    
            app.newframe;
        end

        % Button pushed function: NotreversalButton
        function NotreversalButtonPushed(app, event)
            app.tracks(app.ctrid).revs(...
                app.tracks(app.ctrid).revs==app.t) = [];
            app.xlims = get(app.UIAxes,'xlim')-app.frames(app.t).drift(1);
            app.ylims = get(app.UIAxes,'ylim')-app.frames(app.t).drift(2);
                    
            app.newframe;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Status = uilabel(app.UIFigure);
            app.Status.Visible = 'off';
            app.NewtrackButton = uibutton(app.UIFigure, 'push');
            app.NewtrackButton.ButtonPushedFcn = createCallbackFcn(app, @NewtrackButtonPushed, true);
            app.NewtrackButton.Text = 'New track';
            app.TrackingcellIDSpinnerLabel = uilabel(app.UIFigure);
            app.TrackingcellIDSpinnerLabel.Text = 'Tracking cell ID:';
            app.TrackingcellIDSpinner = uispinner(app.UIFigure);
            app.TrackingcellIDSpinner.Limits = [1 1];
            app.TrackingcellIDSpinner.RoundFractionalValues = 'on';
            app.TrackingcellIDSpinner.ValueChangedFcn = createCallbackFcn(app, @TrackingcellIDSpinnerValueChanged, true);
            app.TrackingcellIDSpinner.Value = 1;
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Enable = 'off';
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.Enable = 'off';
            app.SetaxislimitsButton = uibutton(app.UIFigure, 'push');
            app.SetaxislimitsButton.ButtonPushedFcn = createCallbackFcn(app, @SetaxislimitsButtonPushed, true);
            app.SetaxislimitsButton.Text = 'Set axis limits';
            app.NewcolorButton = uibutton(app.UIFigure, 'push');
            app.NewcolorButton.ButtonPushedFcn = createCallbackFcn(app, @NewcolorButtonPushed, true);
            app.NewcolorButton.Text = 'New color';
            app.AllcellsCheckBox = uicheckbox(app.UIFigure);
            app.AllcellsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllcellsCheckBoxValueChanged, true);
            app.AllcellsCheckBox.Text = 'All cells';
            app.AllcellsCheckBox.Value = true;
            app.AlltimesCheckBox = uicheckbox(app.UIFigure);
            app.AlltimesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlltimesCheckBoxValueChanged, true);
            app.AlltimesCheckBox.Text = 'All times';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MouseX = uilabel(app.UIFigure);
            app.MouseX.Text = '0';
            app.MouseY = uilabel(app.UIFigure);
            app.MouseY.Text = '0';
            app.NewexperimentButton = uibutton(app.UIFigure, 'push');
            app.NewexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @NewexperimentButtonPushed, true);
            app.NewexperimentButton.Text = 'New experiment';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ClicktrackstLampLabel = uilabel(app.UIFigure);
            app.ClicktrackstLampLabel.Text = 'Click tracks (t)';
            app.ClicktrackstLamp = uilamp(app.UIFigure);
            app.RandompointButton = uibutton(app.UIFigure, 'push');
            app.RandompointButton.ButtonPushedFcn = createCallbackFcn(app, @RandompointButtonPushed, true);
            app.RandompointButton.Text = 'Random point';
            app.ClearpointButton = uibutton(app.UIFigure, 'push');
            app.ClearpointButton.ButtonPushedFcn = createCallbackFcn(app, @ClearpointButtonPushed, true);
            app.ClearpointButton.Text = 'Clear point';
            app.TrackframesLabel = uilabel(app.UIFigure);
            app.TrackframesLabel.Text = '0';
            app.MaxframesEditFieldLabel = uilabel(app.UIFigure);
            app.MaxframesEditFieldLabel.Text = 'Max frames';
            app.MaxframesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxframesEditField.Value = 300;
            app.ReversalButton = uibutton(app.UIFigure, 'push');
            app.ReversalButton.ButtonPushedFcn = createCallbackFcn(app, @ReversalButtonPushed, true);
            app.ReversalButton.Text = 'Reversal';
            app.ReversalsCheckBox = uicheckbox(app.UIFigure);
            app.ReversalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReversalsCheckBoxValueChanged, true);
            app.ReversalsCheckBox.Text = 'Reversals';
            app.ReversalsCheckBox.Value = true;
            app.NotreversalButton = uibutton(app.UIFigure, 'push');
            app.NotreversalButton.ButtonPushedFcn = createCallbackFcn(app, @NotreversalButtonPushed, true);
            app.NotreversalButton.Text = 'Not reversal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = celltracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end