classdef SeeRuns < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UITable              matlab.ui.control.Table
        NEditField           matlab.ui.control.NumericEditField
        NEditFieldLabel      matlab.ui.control.Label
        TempLabel            matlab.ui.control.Label
        SelectButton         matlab.ui.control.Button
        fpathEditField       matlab.ui.control.EditField
        fpathEditFieldLabel  matlab.ui.control.Label
        fixrun               matlab.ui.control.CheckBox
        fixrev               matlab.ui.control.CheckBox
        fixkstiff            matlab.ui.control.CheckBox
        fixkagar             matlab.ui.control.CheckBox
        fixv                 matlab.ui.control.CheckBox
        fixrho               matlab.ui.control.CheckBox
        fixl                 matlab.ui.control.CheckBox
        fixd                 matlab.ui.control.CheckBox
        fixeps               matlab.ui.control.CheckBox
        fpEditField          matlab.ui.control.EditField
        fpEditFieldLabel     matlab.ui.control.Label
        runEditField         matlab.ui.control.NumericEditField
        revEditField         matlab.ui.control.NumericEditField
        KstiffEditField      matlab.ui.control.NumericEditField
        KagarEditField       matlab.ui.control.NumericEditField
        vEditField           matlab.ui.control.NumericEditField
        rhoEditField         matlab.ui.control.NumericEditField
        lEditField           matlab.ui.control.NumericEditField
        dEditField           matlab.ui.control.NumericEditField
        epsEditField         matlab.ui.control.NumericEditField
        LoadButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        alldata % Matrix with all possible folders in it.
        rts % Run times.
    end
    
    methods (Access = private)
        
        function tablize(app)
            datatt = app.alldata;
            rtt = app.rts;

            if app.fixeps.Value
                rtt(datatt(1,:)~=app.epsEditField.Value) = [];
                datatt(:,datatt(1,:)~=app.epsEditField.Value) = [];
            end
            if app.fixd.Value
                rtt(datatt(2,:)~=app.dEditField.Value) = [];
                datatt(:,datatt(2,:)~=app.dEditField.Value) = [];
            end
            if app.fixl.Value
                rtt(datatt(3,:)~=app.lEditField.Value) = [];
                datatt(:,datatt(3,:)~=app.lEditField.Value) = [];
            end
            if app.fixrho.Value
                rtt(datatt(4,:)~=app.rhoEditField.Value) = [];
                datatt(:,datatt(4,:)~=app.rhoEditField.Value) = [];
            end
            if app.fixv.Value
                rtt(datatt(5,:)~=app.vEditField.Value) = [];
                datatt(:,datatt(5,:)~=app.vEditField.Value) = [];
            end
            if app.fixkagar.Value
                rtt(datatt(6,:)~=app.KagarEditField.Value) = [];
                datatt(:,datatt(6,:)~=app.KagarEditField.Value) = [];
            end
            if app.fixkstiff.Value
                rtt(datatt(7,:)~=app.KstiffEditField.Value) = [];
                datatt(:,datatt(7,:)~=app.KstiffEditField.Value) = [];
            end
            if app.fixrev.Value
                rtt(datatt(8,:)~=app.revEditField.Value) = [];
                datatt(:,datatt(8,:)~=app.revEditField.Value) = [];
            end
            if app.fixrun.Value
                rtt(datatt(9,:)~=app.runEditField.Value) = [];
                datatt(:,datatt(9,:)~=app.runEditField.Value) = [];
            end
            
            sz = size(datatt);
            sz = fliplr(sz);
            datat = strings(sz);
            size(datat)
            size(rtt)
            for i = 1:sz(2)
                for j = 1:sz(1)
                    datat(j,i) = sprintf('%g',datatt(i,j));
                end
            end

            datat = [datat, rtt'];

            app.UITable.Data = datat;
            
        end
        
        function loadingbar(app,pct)
            img = zeros(1,100);
            img(1:round(pct)) = 1;
            cla(app.UIAxes);
            imagesc(app.UIAxes,img);
            drawnow
        end
        
        function rt = getrt(app,fpatht)
            files = dir(fpatht);
            files = files(arrayfun(@(x)numel(x.name)>5,files));
            files = files(arrayfun(@(x)x.name(1:5)=="slurm",files));
            if ~isempty(files)
                fname = fullfile(files.folder,files.name);
                [~, B] = system(['tail -n 2 ' fname]);
            else
                B = "?";
            end
            if contains(B,"wall time")
                rt = string(B(end-8:end-4));
            elseif contains(B,"TIME LIMIT")
                rt = "MAX T";
            elseif contains(B, "Press")
                rt = "running";
            else
                rt = "?";
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TempLabel.Visible = 'off';
            app.loadingbar(0);
            app.UIAxes.Colormap = [1 0 0; 0 1 0];
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            fpath = fullfile(app.fpathEditField.Value,...
                app.fpEditField.Value);
            epss = dir(fpath);
            epss(1:2) = [];
            app.alldata = [];
            app.TempLabel.Visible = 'on';
            ct = 0;
            app.TempLabel.FontColor = 'r';
            app.TempLabel.Text = sprintf("Loading... %d",ct);

            for et = 1:numel(epss)
                ename = epss(et).name;
                fpatht = fullfile(fpath,ename);
                ds = dir(fpatht);
                ds(1:2) = [];
                for dt= 1:numel(ds)
                    dname = ds(dt).name;
                    fpatht = fullfile(fpath,ename,dname);
                    ls = dir(fpatht);
                    ls(1:2) = [];
                    for lt = 1:numel(ls)
                        lname = ls(lt).name;
                        fpatht = fullfile(fpath,ename,dname,lname);
                        rhos = dir(fpatht);
                        rhos(1:2) = [];
                        for rhot = 1:numel(rhos)
                            rhoname = rhos(rhot).name;
                            fpatht = fullfile(fpath,ename,dname,lname,...
                                rhoname);
                            vs = dir(fpatht);
                            vs(1:2) = [];
                            for vt = 1:numel(vs)
                                vname = vs(vt).name;
                                fpatht = fullfile(fpath,ename,dname,...
                                    lname,rhoname,vname);
                                Kagars = dir(fpatht);
                                Kagars(1:2) = [];
                                for Kagart = 1:numel(Kagars)
                                    Kagarname = Kagars(Kagart).name;
                                    fpatht = fullfile(fpath,ename,dname,...
                                        lname,rhoname,vname,Kagarname);
                                    Kstiffs = dir(fpatht);
                                    Kstiffs(1:2) = [];
                                    for Kstifft = 1:numel(Kstiffs)
                                        Kstiffname = Kstiffs(Kstifft).name;
                                        fpatht = fullfile(fpath,ename,...
                                            dname,lname,rhoname,vname,...
                                            Kagarname,Kstiffname);
                                        revs = dir(fpatht);
                                        revs(1:2) = [];
                                        for revt = 1:numel(revs)
                                            revname = revs(revt).name;
                                            fpatht = fullfile(fpath,...
                                                ename,dname,lname,...
                                                rhoname,vname,...
                                                Kagarname,Kstiffname,...
                                                revname);
                                            runs = dir(fpatht);
                                            runs(1:2) = [];
                                            for runt = 1:numel(runs)
                                                ct = ct + 1;
                                                runname = runs(runt).name;
                                                fpatht = fullfile(fpath,...
                                                    ename,dname,lname,...
                                                    rhoname,vname,...
                                                    Kagarname,...
                                                    Kstiffname,...
                                                    revname,runname);
                                                [eps, d, l, rho, v, ...
                                                    Kagar, Kstiff, rev, ...
                                                    run, ~] = ...
                                                    getparams(fpatht);
                                                
                                                fpathtt = fullfile(...
                                                    fpatht,'cells');
                                                [~, B] = system(['ls ' ...
                                                    fpathtt ' | wc']);
                                                B = strsplit(B,' ');
                                                N = str2num(B{2});
                                                rt = app.getrt(fpatht);
                                                app.alldata = [app.alldata, ...
                                                    [eps; d; l; rho; v; ...
                                                    Kagar; Kstiff; ...
                                                    rev; run; N]];
                                                app.rts = [app.rts, rt];
                                                app.TempLabel.Text = ...
                                                    sprintf("Loading... %d"...
                                                    ,ct);
                                                app.loadingbar(ct/...
                                                    app.NEditField.Value...
                                                    *100);
                                            end
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
            end

            app.tablize;
            app.TempLabel.FontColor = [0 1 0];
            

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            fpath = uigetdir(app.fpathEditField.Value);
            app.fpathEditField.Value = fpath;

        end

        % Value changed function: KagarEditField, KstiffEditField, 
        % ...and 16 other components
        function fixepsValueChanged(app, event)
            app.tablize;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {};
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            colormap(app.UIAxes, 'turbo')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.epsEditField = uieditfield(app.UIFigure, 'numeric');
            app.epsEditField.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.epsEditField.Value = 5;
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.dEditField.Value = 0.7;
            app.lEditField = uieditfield(app.UIFigure, 'numeric');
            app.lEditField.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.lEditField.Value = 7;
            app.rhoEditField = uieditfield(app.UIFigure, 'numeric');
            app.rhoEditField.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.rhoEditField.Value = 0.3;
            app.vEditField = uieditfield(app.UIFigure, 'numeric');
            app.vEditField.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.vEditField.Value = 5;
            app.KagarEditField = uieditfield(app.UIFigure, 'numeric');
            app.KagarEditField.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.KagarEditField.Value = 300;
            app.KstiffEditField = uieditfield(app.UIFigure, 'numeric');
            app.KstiffEditField.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.KstiffEditField.Value = 100;
            app.revEditField = uieditfield(app.UIFigure, 'numeric');
            app.revEditField.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.revEditField.Value = 8;
            app.runEditField = uieditfield(app.UIFigure, 'numeric');
            app.runEditField.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.fpEditFieldLabel = uilabel(app.UIFigure);
            app.fpEditFieldLabel.Text = 'fp';
            app.fpEditField = uieditfield(app.UIFigure, 'text');
            app.fpEditField.Value = 'PoissonRevs';
            app.fixeps = uicheckbox(app.UIFigure);
            app.fixeps.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.fixeps.Text = '';
            app.fixd = uicheckbox(app.UIFigure);
            app.fixd.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.fixd.Text = '';
            app.fixl = uicheckbox(app.UIFigure);
            app.fixl.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.fixl.Text = '';
            app.fixrho = uicheckbox(app.UIFigure);
            app.fixrho.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.fixrho.Text = '';
            app.fixv = uicheckbox(app.UIFigure);
            app.fixv.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.fixv.Text = '';
            app.fixkagar = uicheckbox(app.UIFigure);
            app.fixkagar.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.fixkagar.Text = '';
            app.fixkstiff = uicheckbox(app.UIFigure);
            app.fixkstiff.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.fixkstiff.Text = '';
            app.fixrev = uicheckbox(app.UIFigure);
            app.fixrev.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.fixrev.Text = '';
            app.fixrun = uicheckbox(app.UIFigure);
            app.fixrun.ValueChangedFcn = createCallbackFcn(app, @fixepsValueChanged, true);
            app.fixrun.Text = '';
            app.fpathEditFieldLabel = uilabel(app.UIFigure);
            app.fpathEditFieldLabel.Text = 'fpath';
            app.fpathEditField = uieditfield(app.UIFigure, 'text');
            app.fpathEditField.Value = '/scratch/gpfs/kc32/lammps-myxo-data';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.TempLabel = uilabel(app.UIFigure);
            app.TempLabel.Text = 'Temp';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 300;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'eps'; 'd'; 'l'; 'rho'; 'v'; 'Kagar'; 'Kstiff'; 'rev'; 'run'; 'Done'; 'Time'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeeRuns

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end